classdef cheat_menu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        RemoveButton                matlab.ui.control.Button
        AddButton                   matlab.ui.control.Button
        Level7Field                 matlab.ui.control.NumericEditField
        KhoslasEditFieldLabel       matlab.ui.control.Label
        Level6Field                 matlab.ui.control.NumericEditField
        ProfessorsEditFieldLabel    matlab.ui.control.Label
        Level5Field                 matlab.ui.control.NumericEditField
        PHDStudentsEditFieldLabel   matlab.ui.control.Label
        Level4Field                 matlab.ui.control.NumericEditField
        RaspberryPisEditFieldLabel  matlab.ui.control.Label
        Level3Field                 matlab.ui.control.NumericEditField
        WiFiRoutersEditFieldLabel   matlab.ui.control.Label
        Level2Field                 matlab.ui.control.NumericEditField
        StolenBikesEditFieldLabel   matlab.ui.control.Label
        Level1Field                 matlab.ui.control.NumericEditField
        HDHTraysEditFieldLabel      matlab.ui.control.Label
        PointsField                 matlab.ui.control.NumericEditField
        PointsEditFieldLabel        matlab.ui.control.Label
        SetButton                   matlab.ui.control.Button
        JokeLabel                   matlab.ui.control.Label
        TitleLabel                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.DebugMenuButton.Enable = 'on';

            delete(app);
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            app.CallingApp.points = app.PointsField.Value;
            app.CallingApp.level1 = app.Level1Field.Value;
            app.CallingApp.level2 = app.Level2Field.Value;
            app.CallingApp.level3 = app.Level3Field.Value;
            app.CallingApp.level4 = app.Level4Field.Value;
            app.CallingApp.level5 = app.Level5Field.Value;
            app.CallingApp.level6 = app.Level6Field.Value;
            app.CallingApp.level7 = app.Level7Field.Value;
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            app.CallingApp.points = app.CallingApp.points + app.PointsField.Value;
            app.CallingApp.level1 = app.CallingApp.level1 + app.Level1Field.Value;
            app.CallingApp.level2 = app.CallingApp.level2 + app.Level2Field.Value;
            app.CallingApp.level3 = app.CallingApp.level3 + app.Level3Field.Value;
            app.CallingApp.level4 = app.CallingApp.level4 + app.Level4Field.Value;
            app.CallingApp.level5 = app.CallingApp.level5 + app.Level5Field.Value;
            app.CallingApp.level6 = app.CallingApp.level6 + app.Level6Field.Value;
            app.CallingApp.level7 = app.CallingApp.level7 + app.Level7Field.Value;
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            app.CallingApp.points = app.CallingApp.points - app.PointsField.Value;
            app.CallingApp.level1 = app.CallingApp.level1 - app.Level1Field.Value;
            app.CallingApp.level2 = app.CallingApp.level2 - app.Level2Field.Value;
            app.CallingApp.level3 = app.CallingApp.level3 - app.Level3Field.Value;
            app.CallingApp.level4 = app.CallingApp.level4 - app.Level4Field.Value;
            app.CallingApp.level5 = app.CallingApp.level5 - app.Level5Field.Value;
            app.CallingApp.level6 = app.CallingApp.level6 - app.Level6Field.Value;
            app.CallingApp.level7 = app.CallingApp.level7 - app.Level7Field.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = 'CHEATER MENU';
            app.JokeLabel = uilabel(app.UIFigure);
            app.JokeLabel.Text = 'You are a dirty cheater...';
            app.SetButton = uibutton(app.UIFigure, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.PointsEditFieldLabel = uilabel(app.UIFigure);
            app.PointsEditFieldLabel.Text = 'Points';
            app.PointsField = uieditfield(app.UIFigure, 'numeric');
            app.HDHTraysEditFieldLabel = uilabel(app.UIFigure);
            app.HDHTraysEditFieldLabel.Text = 'HDH Trays';
            app.Level1Field = uieditfield(app.UIFigure, 'numeric');
            app.StolenBikesEditFieldLabel = uilabel(app.UIFigure);
            app.StolenBikesEditFieldLabel.Text = 'Stolen Bikes';
            app.Level2Field = uieditfield(app.UIFigure, 'numeric');
            app.WiFiRoutersEditFieldLabel = uilabel(app.UIFigure);
            app.WiFiRoutersEditFieldLabel.Text = 'WiFi Routers';
            app.Level3Field = uieditfield(app.UIFigure, 'numeric');
            app.RaspberryPisEditFieldLabel = uilabel(app.UIFigure);
            app.RaspberryPisEditFieldLabel.Text = 'Raspberry Pis';
            app.Level4Field = uieditfield(app.UIFigure, 'numeric');
            app.PHDStudentsEditFieldLabel = uilabel(app.UIFigure);
            app.PHDStudentsEditFieldLabel.Text = 'PHD Students';
            app.Level5Field = uieditfield(app.UIFigure, 'numeric');
            app.ProfessorsEditFieldLabel = uilabel(app.UIFigure);
            app.ProfessorsEditFieldLabel.Text = 'Professors';
            app.Level6Field = uieditfield(app.UIFigure, 'numeric');
            app.KhoslasEditFieldLabel = uilabel(app.UIFigure);
            app.KhoslasEditFieldLabel.Text = 'Khosla(s)...?';
            app.Level7Field = uieditfield(app.UIFigure, 'numeric');
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.RemoveButton = uibutton(app.UIFigure, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cheat_menu(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end