classdef gui_game < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        HowToPlayLabel    matlab.ui.control.Label
        EasterEggLabel    matlab.ui.control.Label
        DebugMenuButton   matlab.ui.control.Button
        Level7Label       matlab.ui.control.Label
        Level6Label       matlab.ui.control.Label
        Level5Label       matlab.ui.control.Label
        Level4Label       matlab.ui.control.Label
        Level3Label       matlab.ui.control.Label
        Level2Label       matlab.ui.control.Label
        Level1Label       matlab.ui.control.Label
        Level7Button      matlab.ui.control.Button
        Level6Button      matlab.ui.control.Button
        Level5Button      matlab.ui.control.Button
        Level4Button      matlab.ui.control.Button
        Level3Button      matlab.ui.control.Button
        Level2Button      matlab.ui.control.Button
        Level1Button      matlab.ui.control.Button
        SellButton        matlab.ui.control.StateButton
        BuyButton         matlab.ui.control.StateButton
        GeiselStoreLabel  matlab.ui.control.Label
        DescriptionLabel  matlab.ui.control.Label
        PointsLabel       matlab.ui.control.Label
        PointsLabelLabel  matlab.ui.control.Label
        TitleLabel        matlab.ui.control.Label
        TridentImage      matlab.ui.control.Image
    end

    
    properties (Access = public)
        points = 0.0;
        buying = true;
        level1 = 0;
        level1price = 10.0;
        level2 = 0;
        level2price = 100.0;
        level3 = 0;
        level3price = 1000.0;
        level4 = 0;
        level4price = 10000.0;
        level5 = 0;
        level5price = 100000.0;
        level6 = 0;
        level6price = 1000000.0;
        level7 = 0;
        level7price = 10000000.0;

        easterEggMessages = { ...
            'This is an easter egg message.' ...
            'Did you know that Gideon is an EE student?' ...
            'Try pressing the Konami code.' ...
            'Visit my website at gideontong.com!' ...
            'This message changes every few seconds.' ...
            'Subscribe to me on YouTube @Gideon Tong' ...
            'Follow me on Instagram @gideon.tong' ...
            'Follow me on Twitter @gideontong' ...
            'Follow me on TikTok @gideontong' ...
            };
        sleep = 0;
    end
    
    properties (Access = private)
        CheatApp; % Description
    end
    
    methods (Access = private)

        function enableDisableButtons(app)
            if app.buying
                if app.points >= app.level1price
                    app.Level1Button.Enable = true;
                else
                    app.Level1Button.Enable = false;
                end
                if app.points >= app.level2price
                    app.Level2Button.Enable = true;
                else
                    app.Level2Button.Enable = false;
                end
                if app.points >= app.level3price
                    app.Level3Button.Enable = true;
                else
                    app.Level3Button.Enable = false;
                end
                if app.points >= app.level4price
                    app.Level4Button.Enable = true;
                else
                    app.Level4Button.Enable = false;
                end
                if app.points >= app.level5price
                    app.Level5Button.Enable = true;
                else
                    app.Level5Button.Enable = false;
                end
                if app.points >= app.level6price
                    app.Level6Button.Enable = true;
                else
                    app.Level6Button.Enable = false;
                end
                if app.points >= app.level7price
                    app.Level7Button.Enable = true;
                else
                    app.Level7Button.Enable = false;
                end
            else
                if app.level1 > 0
                    app.Level1Button.Enable = true;
                else
                    app.Level1Button.Enable = false;
                end
                if app.level2 > 0
                    app.Level2Button.Enable = true;
                else
                    app.Level2Button.Enable = false;
                end
                if app.level3 > 0
                    app.Level3Button.Enable = true;
                else
                    app.Level3Button.Enable = false;
                end
                if app.level4 > 0
                    app.Level4Button.Enable = true;
                else
                    app.Level4Button.Enable = false;
                end
                if app.level5 > 0
                    app.Level5Button.Enable = true;
                else
                    app.Level5Button.Enable = false;
                end
                if app.level6 > 0
                    app.Level6Button.Enable = true;
                else
                    app.Level6Button.Enable = false;
                end
                if app.level7 > 0
                    app.Level7Button.Enable = true;
                else
                    app.Level7Button.Enable = false;
                end
            end
        end

        function updatePoints(app)
            app.PointsLabel.Text = string(cast(app.points, 'uint64'));
        end

        function addPointsInterval(app)
            app.points = app.points + app.level1 * 0.1 + app.level2 * 1 ...
                + app.level3 * 10 + app.level4 * 100 + app.level5 * 1000 ...
                + app.level6 * 10000 + app.level7 * 100000;
        end
        
        function background_loop(app)
            while true
                app.updatePoints();
                app.updateAllPrices();
                app.enableDisableButtons();
                pause(0.1);
                app.sleep = app.sleep + 0.1;
                app.addPointsInterval();

                if app.sleep >= 3
                    app.sleep = 0;
                    app.EasterEggLabel.Text = app.easterEggMessages(randi(numel(app.easterEggMessages)));
                end
                drawnow limitrate;
            end
        end

        function updateAll(app)
            app.updatePoints();
            app.enableDisableButtons();
            app.updateAllPrices();
        end

    end
    
    methods (Access = public)
        
        function stringOutput = formatCountPrice(~, count, price)
            count = cast(count, 'uint64');
            price = cast(price, 'uint64');
            jf = java.text.DecimalFormat;
            count = string(jf.format(count));
            price = string(jf.format(price));
            stringOutput = strcat("Owned: ", count, ", Price: ", price);
        end

        function updateAllPrices(app)
            app.Level1Label.Text = app.formatCountPrice(app.level1, app.level1price);
            app.Level2Label.Text = app.formatCountPrice(app.level2, app.level2price);
            app.Level3Label.Text = app.formatCountPrice(app.level3, app.level3price);
            app.Level4Label.Text = app.formatCountPrice(app.level4, app.level4price);
            app.Level5Label.Text = app.formatCountPrice(app.level5, app.level5price);
            app.Level6Label.Text = app.formatCountPrice(app.level6, app.level6price);
            app.Level7Label.Text = app.formatCountPrice(app.level7, app.level7price);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function on_start(app)
            app.updateAllPrices();
            app.background_loop();
        end

        % Image clicked function: TridentImage
        function trident_click(app, event)
            app.TridentImage.ScaleMethod = 'fill';
            app.points = app.points + 1;
            pause(0.07);
            app.TridentImage.ScaleMethod = 'fit';
        end

        % Value changed function: BuyButton
        function BuyButtonValueChanged(app, event)
            app.buying = true;
            app.SellButton.Value = false;
            app.enableDisableButtons();
        end

        % Value changed function: SellButton
        function SellButtonValueChanged(app, event)
            app.buying = false;
            app.BuyButton.Value = false;
            app.enableDisableButtons();
        end

        % Button pushed function: DebugMenuButton
        function DebugMenuButtonPushed(app, event)
            app.DebugMenuButton.Enable = 'off';
            app.CheatApp = cheat_menu(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.CheatApp);
            delete(app);
        end

        % Button pushed function: Level1Button
        function Level1ButtonPushed(app, event)
            if app.buying
                app.level1 = app.level1 + 1;
                app.points = app.points - app.level1price;
                app.level1price = app.level1price * 1.1;
            else
                app.level1 = app.level1 - 1;
                app.points = app.points + (app.level1price * 0.5);
                app.level1price = app.level1price / 1.1;
            end

            app.updateAll();
        end

        % Button pushed function: Level2Button
        function Level2ButtonPushed(app, event)
            if app.buying
                app.level2 = app.level2 + 1;
                app.points = app.points - app.level2price;
                app.level2price = app.level2price * 1.1;
            else
                app.level2 = app.level2 - 1;
                app.points = app.points + (app.level2price * 0.5);
                app.level2price = app.level2price / 1.1;
            end

            app.updateAll();
        end

        % Button pushed function: Level3Button
        function Level3ButtonPushed(app, event)
            if app.buying
                app.level3 = app.level3 + 1;
                app.points = app.points - app.level3price;
                app.level3price = app.level3price * 1.1;
            else
                app.level3 = app.level3 - 1;
                app.points = app.points + (app.level3price * 0.5);
                app.level3price = app.level3price / 1.1;
            end

            app.updateAll();
        end

        % Button pushed function: Level4Button
        function Level4ButtonPushed(app, event)
            if app.buying
                app.level4 = app.level4 + 1;
                app.points = app.points - app.level4price;
                app.level4price = app.level4price * 1.1;
            else
                app.level4 = app.level4 - 1;
                app.points = app.points + (app.level4price * 0.5);
                app.level4price = app.level4price / 1.1;
            end

            app.updateAll();
        end

        % Button pushed function: Level5Button
        function Level5ButtonPushed(app, event)
            if app.buying
                app.level5 = app.level5 + 1;
                app.points = app.points - app.level5price;
                app.level5price = app.level5price * 1.1;
            else
                app.level5 = app.level5 - 1;
                app.points = app.points + (app.level5price * 0.5);
                app.level5price = app.level5price / 1.1;
            end

            app.updateAll();
        end

        % Button pushed function: Level6Button
        function Level6ButtonPushed(app, event)
            if app.buying
                app.level6 = app.level6 + 1;
                app.points = app.points - app.level6price;
                app.level6price = app.level6price * 1.1;
            else
                app.level6 = app.level6 - 1;
                app.points = app.points + (app.level6price * 0.6);
                app.level6price = app.level6price / 1.1;
            end

            app.updateAll();
        end

        % Button pushed function: Level7Button
        function Level7ButtonPushed(app, event)
            if app.buying
                app.level7 = app.level7 + 1;
                app.points = app.points - app.level7price;
                app.level7price = app.level7price * 1.1;
            else
                app.level7 = app.level7 - 1;
                app.points = app.points + (app.level7price * 0.7);
                app.level7price = app.level7price / 1.1;
            end

            app.updateAll();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TridentImage = uiimage(app.UIFigure);
            app.TridentImage.ImageClickedFcn = createCallbackFcn(app, @trident_click, true);
            app.TridentImage.ImageSource = 'trident.png';
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = 'Triton Clicker';
            app.PointsLabelLabel = uilabel(app.UIFigure);
            app.PointsLabelLabel.Text = 'Triton Cash Balance:';
            app.PointsLabel = uilabel(app.UIFigure);
            app.PointsLabel.Text = '0';
            app.DescriptionLabel = uilabel(app.UIFigure);
            app.DescriptionLabel.Text = 'An unbelievable waste of time made by Gideon Tong.';
            app.GeiselStoreLabel = uilabel(app.UIFigure);
            app.GeiselStoreLabel.Text = 'Geisel Store';
            app.BuyButton = uibutton(app.UIFigure, 'state');
            app.BuyButton.ValueChangedFcn = createCallbackFcn(app, @BuyButtonValueChanged, true);
            app.BuyButton.Text = 'Buy';
            app.BuyButton.Value = true;
            app.SellButton = uibutton(app.UIFigure, 'state');
            app.SellButton.ValueChangedFcn = createCallbackFcn(app, @SellButtonValueChanged, true);
            app.SellButton.Text = 'Sell';
            app.Level1Button = uibutton(app.UIFigure, 'push');
            app.Level1Button.ButtonPushedFcn = createCallbackFcn(app, @Level1ButtonPushed, true);
            app.Level1Button.Text = 'HDH Tray';
            app.Level2Button = uibutton(app.UIFigure, 'push');
            app.Level2Button.ButtonPushedFcn = createCallbackFcn(app, @Level2ButtonPushed, true);
            app.Level2Button.Text = 'Stolen Bike';
            app.Level3Button = uibutton(app.UIFigure, 'push');
            app.Level3Button.ButtonPushedFcn = createCallbackFcn(app, @Level3ButtonPushed, true);
            app.Level3Button.Text = 'WiFi Router';
            app.Level4Button = uibutton(app.UIFigure, 'push');
            app.Level4Button.ButtonPushedFcn = createCallbackFcn(app, @Level4ButtonPushed, true);
            app.Level4Button.Text = 'Raspberry Pi';
            app.Level5Button = uibutton(app.UIFigure, 'push');
            app.Level5Button.ButtonPushedFcn = createCallbackFcn(app, @Level5ButtonPushed, true);
            app.Level5Button.Text = 'PHD Student';
            app.Level6Button = uibutton(app.UIFigure, 'push');
            app.Level6Button.ButtonPushedFcn = createCallbackFcn(app, @Level6ButtonPushed, true);
            app.Level6Button.Text = 'Professor';
            app.Level7Button = uibutton(app.UIFigure, 'push');
            app.Level7Button.ButtonPushedFcn = createCallbackFcn(app, @Level7ButtonPushed, true);
            app.Level7Button.Text = 'Khosla';
            app.Level1Label = uilabel(app.UIFigure);
            app.Level1Label.Text = 'Owned: 0, Price: 0';
            app.Level2Label = uilabel(app.UIFigure);
            app.Level2Label.Text = 'Owned: 0, Price: 0';
            app.Level3Label = uilabel(app.UIFigure);
            app.Level3Label.Text = 'Owned: 0, Price: 0';
            app.Level4Label = uilabel(app.UIFigure);
            app.Level4Label.Text = 'Owned: 0, Price: 0';
            app.Level5Label = uilabel(app.UIFigure);
            app.Level5Label.Text = 'Owned: 0, Price: 0';
            app.Level6Label = uilabel(app.UIFigure);
            app.Level6Label.Text = 'Owned: 0, Price: 0';
            app.Level7Label = uilabel(app.UIFigure);
            app.Level7Label.Text = 'Owned: 0, Price: 0';
            app.DebugMenuButton = uibutton(app.UIFigure, 'push');
            app.DebugMenuButton.ButtonPushedFcn = createCallbackFcn(app, @DebugMenuButtonPushed, true);
            app.DebugMenuButton.Text = 'Debug Menu';
            app.EasterEggLabel = uilabel(app.UIFigure);
            app.EasterEggLabel.Text = 'Why hello there...';
            app.HowToPlayLabel = uilabel(app.UIFigure);
            app.HowToPlayLabel.Text = 'Just start clicking the trident to begin playing.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_game

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @on_start)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end